classdef EcuacionesNoL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        NewtonRapsonTab          matlab.ui.container.Tab
        RazEditField_2           matlab.ui.control.NumericEditField
        RazEditField_2Label      matlab.ui.control.Label
        ToleraciaEditField       matlab.ui.control.NumericEditField
        ToleraciaEditFieldLabel  matlab.ui.control.Label
        x0EditField              matlab.ui.control.NumericEditField
        x0EditFieldLabel         matlab.ui.control.Label
        fxEditField_2            matlab.ui.control.EditField
        fxEditField_2Label       matlab.ui.control.Label
        PlayButton_2             matlab.ui.control.Button
        GrficaButton_2           matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        NewtonMultivariableTab   matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
         
        function x1=newton(~,f,x0,tol)
            df=diff(f); % Derivada
            x00=x0;
            for k=1:m
                fx0=double(subs(f,x0)); % f(x0)
                dfx0=double(subs(df,x0)); % f'(x0)
                x1=x0-fx0/dfx0;
                err=abs(x1-x0); % error absoluto
                if err<tol
                    break
                end
                x0=x1;
            end
            x0=num2str(x0);
            
        end
        function [X1,err,E] = Newton_Multivariable(~,F,X0,tol,m)
            J=jacobian(F); %matriz jacobiana
            X=symvar(F); % vector de variables independientes
            for k=1:m   % Son las iteraciones máximas
                FX0=double(subs(F,X,X0));    %F(X0)
                JX0=double(subs(J,X,X0));    %J(X0)
                X1=X0'-JX0\FX0';
                err=norm(X1-X0'); % aquí tildeamos el X0 por las dimensiones, X1 es vector columna y X0 nos lo da en renglón po eso lo trasponemos.
                if err<tol
                    break
                else
                    X0=X1';
                end
            end
            if err<tol
                E='La aproximacion está dentro de la tolerancia indicada';
            else
                E='La aproximacion NO esta dentro de la tolerancia indicada';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function GrficaButtonPushed(app, event)
            f=app.fxEditField.Value; %Extraer la función con cadena de texto
            A=['@(x)',f];
            f=str2func(A); %Tranforma la cadena de texto en un cadena anonima

            a=app.aEditField.Value; %Extrae el valor de a (lim inferior)
            b=app.bEditField.Value; %Extrae el valor de b (lin superior)

            fplot(app.UIAxes,f,[a,b]); %Grafica la función respecto a sus limites
        end

        % Callback function: not associated with a component
        function PlayButtonPushed(app, event)
            f=app.fxEditField.Value; %Extraer la función con cadena de texto
            A=['@(x)',f];
            f=str2func(A); %Tranforma la cadena de texto en un cadena anonima
            a=app.aEditField.Value; %Extrae el v'alor de a (lim inferior)
            b=app.bEditField.Value; %Extrae el valor de b (lin superior)
            tol=app.toleranciaEditField.Value;

            c=biseccion(f,a,b,tol); %Manda a llmar el método ed bisección
            app.RazEditField.Value=c;

        end

        % Button pushed function: GrficaButton_2
        function GrficaButton_2Pushed(app, event)
          f=app.fxEditField_2.Value; %Extraer la función con cadena de texto
          f=str2func(['@(x)',f]); %Tranforma la cadena de texto en un cadena anonima
          fplot(app.UIAxes2,f); %Grafica la función respecto a sus limites  
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
          syms x;
          f=app.fxEditField_2.Value; %Extraer la función con cadena de texto
          A=['@(x)',f];
          f=str2func(A); %Tranforma la cadena de texto en un cadena anonima
          F=f(x);
          tol.app
          %Transforma una vareable anonima a una variable simbolica
          x0=app.x0EditField.Value;
          x1=newton(F,x0,tol);
          %Se asigna el valor en la raiz
          app.RazEditField_2.Value=x1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NewtonRapsonTab = uitab(app.TabGroup);
            app.NewtonRapsonTab.Title = 'NewtonRapson';
            app.UIAxes2 = uiaxes(app.NewtonRapsonTab);
            title(app.UIAxes2, 'Netodo de Newton -Rapson')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GrficaButton_2 = uibutton(app.NewtonRapsonTab, 'push');
            app.GrficaButton_2.ButtonPushedFcn = createCallbackFcn(app, @GrficaButton_2Pushed, true);
            app.GrficaButton_2.Text = 'Gráfica';
            app.PlayButton_2 = uibutton(app.NewtonRapsonTab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.fxEditField_2Label = uilabel(app.NewtonRapsonTab);
            app.fxEditField_2Label.Text = 'f(x)';
            app.fxEditField_2 = uieditfield(app.NewtonRapsonTab, 'text');
            app.fxEditField_2.Value = 'x¨2';
            app.x0EditFieldLabel = uilabel(app.NewtonRapsonTab);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.NewtonRapsonTab, 'numeric');
            app.x0EditField.Value = 3;
            app.ToleraciaEditFieldLabel = uilabel(app.NewtonRapsonTab);
            app.ToleraciaEditFieldLabel.Text = 'Toleracia';
            app.ToleraciaEditField = uieditfield(app.NewtonRapsonTab, 'numeric');
            app.ToleraciaEditField.Value = 1e-06;
            app.RazEditField_2Label = uilabel(app.NewtonRapsonTab);
            app.RazEditField_2Label.Text = 'Raíz';
            app.RazEditField_2 = uieditfield(app.NewtonRapsonTab, 'numeric');
            app.NewtonMultivariableTab = uitab(app.TabGroup);
            app.NewtonMultivariableTab.Title = 'Newton Multivariable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EcuacionesNoL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end