classdef Ecuaciones_No_Lineales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Ecuaciones_NL              matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        UnaVariableTab             matlab.ui.container.Tab
        RazEditField               matlab.ui.control.EditField
        RazEditFieldLabel          matlab.ui.control.Label
        x0EditField                matlab.ui.control.NumericEditField
        x0EditFieldLabel           matlab.ui.control.Label
        PlayButton                 matlab.ui.control.Button
        GrficoButton               matlab.ui.control.Button
        MtodoDropDown              matlab.ui.control.DropDown
        MtodoDropDownLabel         matlab.ui.control.Label
        TolereanciaEditField       matlab.ui.control.NumericEditField
        TolereanciaEditFieldLabel  matlab.ui.control.Label
        bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        fxEditField                matlab.ui.control.EditField
        fxEditFieldLabel           matlab.ui.control.Label
        Grafico                    matlab.ui.control.UIAxes
        VariasVariablesTab         matlab.ui.container.Tab
        Solucion                   matlab.ui.control.Table
        MsjEditField               matlab.ui.control.EditField
        MsjEditFieldLabel          matlab.ui.control.Label
        ErrorEditField             matlab.ui.control.NumericEditField
        ErrorEditFieldLabel        matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        EcuacionesEditField        matlab.ui.control.NumericEditField
        EcuacionesEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        EcuacionesButton           matlab.ui.control.Button
        Inicio                     matlab.ui.control.Table
        Ecuaciones                 matlab.ui.control.Table
        PlayButton_2               matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function c = Biseccion(~,f,a,b,tol)
            n = log((b-a)/tol)/log(2);
            n = ceil(n); % entero inmediato superior a n
            
            if f(a)*f(b)>0
                c= 'Error';
                return;
            else
                for k = 1:n
                    c = (a+b)/2; % punto medio
                    
                    if f(a)*f(b)<0
                        b = c; % cambio de valor
                    else
                        a=c; % cambio de valor
                    end
                end
            end
            c = num2str(c);
        end
        
        function x1 = Newton(~,f,x0,tol)
            fx0 = double(subs(f,x0)); %f(x0)
            df=diff(f); % derivada de la función f
            dfx0 = double(subs(df,x0)); %f'(x0)
            x1 = x0-fx0/dfx0;
            err = abs(x1-x0); % Error absoluto
            while err >= tol && dfx0 ~= 0
                x0=x1;
                fx0=double(subs(f,x0));
                dfx0 = double(subs(df,x0));
                x1 = x0-fx0/dfx0;
                err = abs(x1-x0);
            end
            x1 = num2str(x1);
        end
        
        function [X1,err,E] = Newton_Multivariable(~,F,X0,tol,m)
            J=jacobian(F); %matriz jacobiana
            X=symvar(F); % vector de variables independientes
            for k=1:m   % Son las iteraciones máximas
                FX0=double(subs(F,X,X0));    %F(X0)
                JX0=double(subs(J,X,X0));    %J(X0)
                X1=X0'-JX0\FX0';
                err=norm(X1-X0'); % aquí tildeamos el X0 por las dimensiones, 
                % X1 es vector columna y X0 nos lo da en renglón po eso lo trasponemos.
                if err<tol
                    break
                else
                    X0=X1';
                end
            end
            if err<tol
                E='La aproximacion está dentro de la tolerancia indicada';
            else
                E='La aproximacion NO esta dentro de la tolerancia indicada';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            if value == 'A' % Método de Newton Raphson
                app.x0EditField.Visible = true; % Muestra el campo x0
                app.x0EditFieldLabel.Visible = true; % Muestra la etiqueta del campo x0
                app.Grafico.Title.String = "Método Newton-Raphson"; % Agrega un título al gráfico
            elseif value == 'B' % Método de Bisección
                app.x0EditField.Visible = false; % Oculta el campo x0
                app.x0EditFieldLabel.Visible = false; % Oculta la etiqueta del campo x0
                app.Grafico.Title.String = "Método Bisección"; % Agrega un título al gráfico
            end
        end

        % Button pushed function: GrficoButton
        function GrficoButtonPushed(app, event)
            f=app.fxEditField.Value; % Extrae la función en Texto
            f = ['@(x)',f];
            f=str2func(f); % Convierte el texto a función anónima
            
            a = app.aEditField.Value; % Límite inferior
            b = app.bEditField.Value; % Límite Superior
            
            fplot(app.Grafico,f,[a,b],'Color',[0.07,0.27,0.35],'LineWidth',2); % Gráfica la función respecto a sus límites
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            metodo = app.MtodoDropDown.Value; % Extrae el tipo de método a utilizar

            f=app.fxEditField.Value; % Extrae la función en Texto
            f = ['@(x)',f];
            f=str2func(f); % Convierte el texto a función anónima
            
            a = app.aEditField.Value; % Límite inferior
            b = app.bEditField.Value; % Límite Superior
            tol = app.TolereanciaEditField.Value; % La tolerancia especificada

            if metodo == 'A' % Método de Newton - Raphson
                x0 = app.x0EditField.Value; 
                f = sym(f);
                c = app.Newton(f,x0,tol);
            elseif metodo == 'B' % Método de Bisección
                c = app.Biseccion(f,a,b,tol);
            end
            app.RazEditField.Value = c; % Asigna el valor de la raiz a el campo "Raiz"
        end

        % Button pushed function: EcuacionesButton
        function EcuacionesButtonPushed(app, event)
            N = app.EcuacionesEditField.Value; % Extrae el número de ecuaciones
            data = cell(N,1); % Crea las celdas necesarias para las tres tablas
            app.Ecuaciones.Data = data; % Tabla para ecuaciones
            app.Inicio.Data = data; % Tabla para los puntos iniciales
            app.Solucion.Data = data; % Trabla para la solución
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            X = app.Inicio.Data; % Extrae los valores de inicio

            m = app.IteracionesEditField.Value; % Extrae el número de iteraciones necesarias
            tol = app.ToleranciaEditField.Value; % Extrae la tolerancia
            N = app.EcuacionesEditField.Value; % Extrae el número de ecuaciones

            T = app.Ecuaciones.Data; % Extrae las ecuaciones en forma de texto

            F = []; % Crea un vector vacio donde se almacenarán las ecuaciones de forma simbólica
            X0 =zeros(1,N); % Crea una matriz de ceros, donde se guardará los valores inciales.

            for i = 1:N % Corre y alamacena las ecuaciones
                f = str2sym(T{i}); % Convierte el texto a ecuaciones simbólicas
                F = [F,f]; % Guarda las ecuaciones en el vector F
                X0(i) = X{i}; % Extrae y guarda los valores inciales en X0
            end
            
            [X1,err,E] = app.Newton_Multivariable(F,X0,tol,m); % Llama el método de Newton Multivariables
            app.Solucion.Data = X1; % Asigna la solución a la tabla "Solución"
            app.ErrorEditField.Value = err; % Asigna el valor del error al campo "Error"
            app.MsjEditField.Value = E; % Asigna el mensaje de solución.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Ecuaciones_NL = uifigure('Visible', 'off');
            app.Ecuaciones_NL.Name = 'Ecuaciones No Lineales';
            app.TabGroup = uitabgroup(app.Ecuaciones_NL);
            app.UnaVariableTab = uitab(app.TabGroup);
            app.UnaVariableTab.Title = 'Una Variable';
            app.Grafico = uiaxes(app.UnaVariableTab);
            title(app.Grafico, 'Método Newton-Rahpson')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.fxEditFieldLabel = uilabel(app.UnaVariableTab);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UnaVariableTab, 'text');
            app.fxEditField.Value = 'x^2';
            app.aEditFieldLabel = uilabel(app.UnaVariableTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UnaVariableTab, 'numeric');
            app.aEditField.Value = -2;
            app.bEditFieldLabel = uilabel(app.UnaVariableTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UnaVariableTab, 'numeric');
            app.bEditField.Value = 3;
            app.TolereanciaEditFieldLabel = uilabel(app.UnaVariableTab);
            app.TolereanciaEditFieldLabel.Text = 'Tolereancia';
            app.TolereanciaEditField = uieditfield(app.UnaVariableTab, 'numeric');
            app.TolereanciaEditField.Value = 1e-06;
            app.MtodoDropDownLabel = uilabel(app.UnaVariableTab);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.UnaVariableTab);
            app.MtodoDropDown.Items = {'Newton-Raphson', 'Bisección'};
            app.MtodoDropDown.ItemsData = {'A', 'B'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'A';
            app.GrficoButton = uibutton(app.UnaVariableTab, 'push');
            app.GrficoButton.ButtonPushedFcn = createCallbackFcn(app, @GrficoButtonPushed, true);
            app.GrficoButton.Text = 'Gráfico';
            app.PlayButton = uibutton(app.UnaVariableTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.x0EditFieldLabel = uilabel(app.UnaVariableTab);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UnaVariableTab, 'numeric');
            app.x0EditField.Value = 3;
            app.RazEditFieldLabel = uilabel(app.UnaVariableTab);
            app.RazEditFieldLabel.Text = 'Raíz';
            app.RazEditField = uieditfield(app.UnaVariableTab, 'text');
            app.RazEditField.Editable = 'off';
            app.RazEditField.Value = '0.0000';
            app.VariasVariablesTab = uitab(app.TabGroup);
            app.VariasVariablesTab.Title = 'Varias Variables';
            app.PlayButton_2 = uibutton(app.VariasVariablesTab, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.Ecuaciones = uitable(app.VariasVariablesTab);
            app.Ecuaciones.ColumnName = {'Ecuaciones'};
            app.Ecuaciones.RowName = {};
            app.Ecuaciones.ColumnEditable = true;
            app.Inicio = uitable(app.VariasVariablesTab);
            app.Inicio.ColumnName = {'Inicio'};
            app.Inicio.RowName = {};
            app.Inicio.ColumnEditable = true;
            app.EcuacionesButton = uibutton(app.VariasVariablesTab, 'push');
            app.EcuacionesButton.ButtonPushedFcn = createCallbackFcn(app, @EcuacionesButtonPushed, true);
            app.EcuacionesButton.Text = 'Ecuaciones';
            app.ToleranciaEditFieldLabel = uilabel(app.VariasVariablesTab);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.VariasVariablesTab, 'numeric');
            app.ToleranciaEditField.Value = 1e-06;
            app.EcuacionesEditFieldLabel = uilabel(app.VariasVariablesTab);
            app.EcuacionesEditFieldLabel.Text = '# Ecuaciones';
            app.EcuacionesEditField = uieditfield(app.VariasVariablesTab, 'numeric');
            app.EcuacionesEditField.Value = 3;
            app.IteracionesEditFieldLabel = uilabel(app.VariasVariablesTab);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.VariasVariablesTab, 'numeric');
            app.IteracionesEditField.Value = 10;
            app.ErrorEditFieldLabel = uilabel(app.VariasVariablesTab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.VariasVariablesTab, 'numeric');
            app.ErrorEditField.Editable = 'off';
            app.MsjEditFieldLabel = uilabel(app.VariasVariablesTab);
            app.MsjEditFieldLabel.Text = 'Msj';
            app.MsjEditField = uieditfield(app.VariasVariablesTab, 'text');
            app.MsjEditField.Editable = 'off';
            app.MsjEditField.Value = 'Aún no se ha ejecutado el programa';
            app.Solucion = uitable(app.VariasVariablesTab);
            app.Solucion.ColumnName = {'Solución'};
            app.Solucion.RowName = {};
            % Show the figure after all components are created
            app.Ecuaciones_NL.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ecuaciones_No_Lineales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Ecuaciones_NL)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Ecuaciones_NL)
        end
    end
end