classdef Integrales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Integracion                matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        IntegralSimpleTab          matlab.ui.container.Tab
        nEditField                 matlab.ui.control.NumericEditField
        nEditFieldLabel            matlab.ui.control.Label
        LmSupEditField             matlab.ui.control.NumericEditField
        LmSupEditFieldLabel        matlab.ui.control.Label
        ResultadoEditField         matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel    matlab.ui.control.Label
        MtodoDropDown              matlab.ui.control.DropDown
        MtodoDropDownLabel         matlab.ui.control.Label
        LmInfEditField             matlab.ui.control.NumericEditField
        LmInfEditFieldLabel        matlab.ui.control.Label
        fxEditField                matlab.ui.control.EditField
        fxEditFieldLabel           matlab.ui.control.Label
        IntegrarButton             matlab.ui.control.Button
        Grafico                    matlab.ui.control.UIAxes
        IntegralMultipleTab        matlab.ui.container.Tab
        fxyzEditField              matlab.ui.control.EditField
        fxyzEditFieldLabel         matlab.ui.control.Label
        TipoDropDown_2             matlab.ui.control.DropDown
        TipoDropDown_2Label        matlab.ui.control.Label
        TipoDropDown               matlab.ui.control.DropDown
        TipoLabel                  matlab.ui.control.Label
        IntegrarButton_2           matlab.ui.control.Button
        ResultadoEditField_2       matlab.ui.control.NumericEditField
        ResultadoEditField_2Label  matlab.ui.control.Label
        fEditField                 matlab.ui.control.EditField
        fEditFieldLabel            matlab.ui.control.Label
        eEditField                 matlab.ui.control.EditField
        eEditFieldLabel            matlab.ui.control.Label
        dEditField                 matlab.ui.control.EditField
        dEditFieldLabel            matlab.ui.control.Label
        cEditField                 matlab.ui.control.EditField
        cEditFieldLabel            matlab.ui.control.Label
        bEditField                 matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        IntegralDropDown           matlab.ui.control.DropDown
        IntegralDropDownLabel      matlab.ui.control.Label
        fxyEditField               matlab.ui.control.EditField
        fxyEditFieldLabel          matlab.ui.control.Label
    end

     methods (Access = private)
        
        function I = Simpson(~,f,a,b,n) % Función del método Simpson un tercio
            h=(b-a)/n;
            u1=0;
            u2=0;
            for j=1:n-1
                if rem(j,2)==0
                    u1=u1+f(a+j*h);
                else
                    u2=u2+f(a+j*h);
                end
            end
            I=(h/3)*(f(a)+f(b)+2*u1+4*u2);
        end
        
        function I = Trapecio(~,f,a,b,n) % Función del método del Trapecio Compuesto
            h=(b-a)/n;
            I=0;
            for i=1:n-1
                xi=a+i*h;
                fxi=f(xi);
                I=I+fxi;
            end
            I=h*I;
            I=(f(a)+f(b))*(h/2)+I;
        end
        
        function I = Gauss_Legendre(~,f,a,b) % Función del método de Gauss-Legendre
            xi=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099 0.1834346425 -0.1834346425];
            wi=[0.1012285363 0.1012285363 0.2223810345 0.2223810345 0.3137066459 0.3137066459 0.3626837834 0.3626837834];
            
            h1=(b-a)/2;
            h2=(a+b)/2;
            I=0;
            for j=1:8
                m=h2+h1*xi(j);
                s=wi(j)*f(m);
                I=s+I;
            end
            I=h1*I;
        end
        
        
        function I = Integral_Doble(~,f,a,b,g1,g2) % Función para calcular la integral Doble
            x=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099 0.1834346425 -0.1834346425];
            w=[0.1012285363 0.1012285363 0.2223810345 0.2223810345 0.3137066459 0.3137066459 0.3626837834 0.3626837834];
            
            t1 = (b-a)/2;
            t2 = (b+a)/2;
            M = 0;
            for i=1:8
                agr =t2+t1*x(i);
                
                ki = (g2(agr)-g1(agr))/2;
                si = (g2(agr)+g1(agr))/2;
                N = 0;
                for j=1:8
                    N = N+w(j)*f(agr, si+ki*x(j));
                end
                M = M+w(i)*ki*N;
            end
            I = t1*M;
        end
        
        function I = Integral_Triple(~,f,a,b,g1,g2,h1,h2) % Función para cálcular la Integral Triple
            x=[0.9602898565 -0.9602898565 0.7966664774 -0.7966664774 0.5255324099 -0.5255324099 0.1834346425 -0.1834346425];
            w=[0.1012285363 0.1012285363 0.2223810345 0.2223810345 0.3137066459 0.3137066459 0.3626837834 0.3626837834];
            
            t1 = (b-a)/2;
            t2 = (b+a)/2;
            
            M = 0;
            for i=1:8
                agr1 = t2+t1*x(i);
                k1 = (g2(agr1)-g1(agr1))/2;
                k2 = (g2(agr1)+g1(agr1))/2;
                
                O = 0;
                for j=1:8
                    agr2 = k2+k1*x(j);
                    s1 = (h2(agr1,agr2)-h1(agr1,agr2))/2;
                    s2 = (h2(agr1,agr2)+h1(agr1,agr2))/2;
                    
                    P = 0;
                    for k=1:8
                        P = P + w(k)*f(agr1, agr2, s2+s1*x(k));
                    end
                    O = O + w(j)*s1*P;
                end
                M = M + w(i)*k1*O;
            end
            I = t1*M;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value; % Extrae el tipo de método a utilizar
            if value == '1' % Utiliza el método de Simpson un tercio
                app.nEditField.Visible = true; % Vuelve visible el campo de "n"
                app.nEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "n"
            elseif value == '2' % Utiliza el método de Gauss_Legendre
                app.nEditField.Visible = false; % Vuelve invisible el campo de "n"
                app.nEditFieldLabel.Visible = false; % Vuelve invisible la etiqueta de "n"
            elseif value == '3' % Utiliza el método del Trapecio Compuesto
                app.nEditField.Visible = true; % Vuelve visible el campo de "n"
                app.nEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "n"
            end
        end

        % Button pushed function: IntegrarButton
        function IntegrarButtonPushed(app, event)
            f = app.fxEditField.Value; % Obtiene la función
            f = str2func(['@(x)',f]); % Transforma el texto a función anónima
            
            a = app.LmInfEditField.Value; % Extrae el límite inferior
            b = app.LmSupEditField.Value; % Extrae el límite superior
            n = app.nEditField.Value; % Extrae el número en que se dividirá el intervalo [a,b]
            
            Met = app.MtodoDropDown.Value; % Extrae el tipo de método a utilizar
        
            if Met == '1'
                I = app.Simpson(f,a,b,n); % Utiliza el método de Simpson un tercio
            elseif Met == '2'
                I = app.Gauss_Legendre(f,a,b); % Utiliza el método de Gauss_Legendre
            elseif Met == '3'
                I = app.Trapecio(f,a,b,n); % Utiliza el método del Trapecio Compuesto
            end
            app.ResultadoEditField.Value = I; % Asigna el resultado a Campo de Resultado en la ventana
            
            % Creación del gráfico
            fplot(app.Grafico,f,[a,b])
            % Creación de reyeno del área bajo la curva
            x = a:0.1:b; % Crea un vector para x
            y = f(x); % Crea el vector en y
            fill(app.Grafico,[x,fliplr(x)],[y,zeros(size(y))],[0.94,0.96,0.88]); % Creal el reyeno del área
        end

        % Value changed function: IntegralDropDown
        function IntegralDropDownValueChanged(app, event)
            value = app.IntegralDropDown.Value; % Obtiene el tipo de integral [Doble o Triple]
            if value == 'a' % Utiliza la Integral Doble
                app.fxyEditField.Visible = true; % Vuelve visible el campo de "fxy"
                app.fxyEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "fxy"
                app.fxyzEditField.Visible = false; % Vuelve invisible el campo de "fxyz"
                app.fxyzEditFieldLabel.Visible = false; % Vuelve invisible la etiqueta de "fxyz"
                app.eEditField.Visible = false; % Vuelve invisible el campo de "e"
                app.eEditFieldLabel.Visible = false; % Vuelve invisible la etiqueta de "e"
                app.fEditField.Visible = false; % Vuelve invisible el campo de "f"
                app.fEditFieldLabel.Visible = false; % Vuelve invisible la etiqueta de "f"
                app.TipoDropDown.Visible = true; % Vuelve visible el Drop Down Tipo
                app.TipoDropDown_2.Visible = false; % Vuelve invisible el Drop Down Tipo_2
            elseif value == 'b' % Utiliza la Integral Triple
                app.fxyEditField.Visible = false; % Vuelve invisible el campo de "fxy"
                app.fxyEditFieldLabel.Visible = false; % Vuelve invisible la etiqueta de "fxy"
                app.fxyzEditField.Visible = true; % Vuelve visible el campo de "fxyz"
                app.fxyzEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "fxyz"
                app.eEditField.Visible = true; % Vuelve visible el campo de "e"
                app.eEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "e"
                app.fEditField.Visible = true; % Vuelve visible el campo de "f"
                app.fEditFieldLabel.Visible = true; % Vuelve visible la etiqueta de "e"
                app.TipoDropDown.Visible = false; % Vuelve invisible el Drop Down Tipo
                app.TipoDropDown_2.Visible = true; % Vuelve visible el Drop Down Tipo_2
            end
        end

        % Button pushed function: IntegrarButton_2
        function IntegrarButton_2Pushed(app, event)
            integral = app.IntegralDropDown.Value;
            
            a = app.aEditField.Value; % Extrae el límite inferior de la tercer integral
            b = app.bEditField.Value; % Extrae el límite superior de la tercer integral
            g1 = app.cEditField.Value; % Extrae el límite inferior de la segunda integral
            g2 = app.dEditField.Value; % Extrae el límite superior de la segunda integral
            h1 = app.eEditField.Value; % Extrae el límite inferior de la primer integral
            h2 = app.fEditField.Value; % Extrae el límite superior de la primer integral
            
            if integral == 'a'
                tip = app.TipoDropDown.Value; % Extrae el tipo de integral
                if tip == 'A' % Asignaciones para la Itegral Doble del Tipo I
                    v='@(x,y)';
                    r='@(x)';
                elseif tip == 'B' % Asignaciones para la Itegral Doble del Tipo II
                    v='@(y,x)';
                    r='@(y)';
                end
                g1=[r,g1];
                g1=str2func(g1); %transformas g1 a función anónima

                g2=[r,g2];
                g2=str2func(g2); %transformas g2 a función anónima
                
                f = app.fxyEditField.Value; % Extrae el valor de la función
                f=[v,f];
                f=str2func(f); % transforma a la función f en anónima
                
                I=app.Integral_Doble(f,a,b,g1,g2); % Realiza la Integración Doble
            elseif 'b'
                tipo = app.TipoDropDown_2.Value;
                if tipo=='A'
                    v='@(x,y,z)';
                    r='@(x,y)';
                    s='@(x)';
                elseif tipo=='B'
                    v='@(y,x,z)';
                    r='@(y,x)';
                    s='@(y)';
                elseif tipo=='C'
                    v='@(z,y,x)';
                    r='@(z,y)';
                    s='@(z)';
                elseif tipo=='D'
                    v='@(y,z,x)';
                    r='@(y,z)';
                    s='@(y)';
                elseif tipo=='E'
                    v='@(z,x,y)';
                    r='@(z,x)';
                    s='@(z)';
                elseif tipo=='F'
                    v='@(x,z,y)';
                    r='@(x,z)';
                    s='@(x)';
                end
                
                f = app.fxyzEditField.Value; % Extrae la función, como cadena de texto
                f=[v,f];
                f=str2func(f); % Convierte la función f de texto a anónima
                
                g1=[s,g1];
                g1=str2func(g1); % Convierte la función g1 de texto a anónima
                
                g2=[s,g2];
                g2=str2func(g2); % Convierte la función g2 de texto a anónima
                
                h1=[r,h1];
                h1=str2func(h1); % Convierte la función h1 de texto a anónima
                
                h2=[r,h2];
                h2=str2func(h2); % Convierte la función h2 de texto a anónima
                
                I=app.Integral_Triple(f,a,b,g1,g2,h1,h2);
            end
            app.ResultadoEditField_2.Value = I;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Integracion = uifigure('Visible', 'off');
            app.Integracion.Name = 'Integración';
            app.TabGroup = uitabgroup(app.Integracion);
            app.IntegralSimpleTab = uitab(app.TabGroup);
            app.IntegralSimpleTab.Title = 'Integral Simple';
            app.Grafico = uiaxes(app.IntegralSimpleTab);
            title(app.Grafico, 'Gráfico de la Función')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            app.IntegrarButton = uibutton(app.IntegralSimpleTab, 'push');
            app.IntegrarButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButtonPushed, true);
            app.IntegrarButton.Text = 'Integrar';
            app.fxEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.IntegralSimpleTab, 'text');
            app.LmInfEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.LmInfEditFieldLabel.Text = 'Lím. Inf.';
            app.LmInfEditField = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.LmInfEditField.Value = -5;
            app.MtodoDropDownLabel = uilabel(app.IntegralSimpleTab);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.IntegralSimpleTab);
            app.MtodoDropDown.Items = {'Simpson', 'Gauss Legendre', 'Trapecio Compuesto'};
            app.MtodoDropDown.ItemsData = {'1', '2', '3'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = '1';
            app.ResultadoEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.ResultadoEditField.ValueDisplayFormat = '%.4f';
            app.ResultadoEditField.Editable = 'off';
            app.LmSupEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.LmSupEditFieldLabel.Text = 'Lím. Sup.';
            app.LmSupEditField = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.LmSupEditField.Value = 5;
            app.nEditFieldLabel = uilabel(app.IntegralSimpleTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.IntegralSimpleTab, 'numeric');
            app.nEditField.Value = 100;
            app.IntegralMultipleTab = uitab(app.TabGroup);
            app.IntegralMultipleTab.Title = 'Integral Multiple';
            app.fxyEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.fxyEditFieldLabel.Text = 'f(x,y)';
            app.fxyEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.IntegralDropDownLabel = uilabel(app.IntegralMultipleTab);
            app.IntegralDropDownLabel.Text = 'Integral';
            app.IntegralDropDown = uidropdown(app.IntegralMultipleTab);
            app.IntegralDropDown.Items = {'Doble', 'Triple'};
            app.IntegralDropDown.ItemsData = {'a', 'b'};
            app.IntegralDropDown.ValueChangedFcn = createCallbackFcn(app, @IntegralDropDownValueChanged, true);
            app.IntegralDropDown.Value = 'b';
            app.aEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.IntegralMultipleTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.IntegralMultipleTab, 'numeric');
            app.cEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.dEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.eEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.eEditFieldLabel.Visible = 'off';
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.eEditField.Visible = 'off';
            app.fEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.fEditFieldLabel.Visible = 'off';
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.fEditField.Visible = 'off';
            app.ResultadoEditField_2Label = uilabel(app.IntegralMultipleTab);
            app.ResultadoEditField_2Label.Text = 'Resultado';
            app.ResultadoEditField_2 = uieditfield(app.IntegralMultipleTab, 'numeric');
            app.ResultadoEditField_2.ValueDisplayFormat = '%.4f';
            app.ResultadoEditField_2.Editable = 'off';
            app.IntegrarButton_2 = uibutton(app.IntegralMultipleTab, 'push');
            app.IntegrarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButton_2Pushed, true);
            app.IntegrarButton_2.Text = 'Integrar';
            app.TipoLabel = uilabel(app.IntegralMultipleTab);
            app.TipoLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.IntegralMultipleTab);
            app.TipoDropDown.Items = {'dxdy', 'dydx'};
            app.TipoDropDown.ItemsData = {'A', 'B'};
            app.TipoDropDown.Value = 'A';
            app.TipoDropDown_2Label = uilabel(app.IntegralMultipleTab);
            app.TipoDropDown_2Label.Visible = 'off';
            app.TipoDropDown_2Label.Text = 'Tipo';
            app.TipoDropDown_2 = uidropdown(app.IntegralMultipleTab);
            app.TipoDropDown_2.Items = {'dzdydx', 'dzdxdy', 'dxdydz', 'dxdzdy', 'dydxdz', 'dydzdx'};
            app.TipoDropDown_2.ItemsData = {'A', 'B', 'C', 'D', 'E', 'F'};
            app.TipoDropDown_2.Visible = 'off';
            app.TipoDropDown_2.Value = 'E';
            app.fxyzEditFieldLabel = uilabel(app.IntegralMultipleTab);
            app.fxyzEditFieldLabel.Visible = 'off';
            app.fxyzEditFieldLabel.Text = 'f(x,y,z)';
            app.fxyzEditField = uieditfield(app.IntegralMultipleTab, 'text');
            app.fxyzEditField.Visible = 'off';
            % Show the figure after all components are created
            app.Integracion.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Integrales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Integracion)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Integracion)
        end
    end
end