classdef InterpolacionLa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        YEditField               matlab.ui.control.EditField
        YEditField_2Label        matlab.ui.control.Label
        XEditField               matlab.ui.control.EditField
        XEditField_2Label        matlab.ui.control.Label
        XInterpolLabel           matlab.ui.control.EditField
        ValoresaInterpolarEditFieldLabel  matlab.ui.control.Label
        InterpolarButton         matlab.ui.control.Button
        ResultadoEditField       matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
            x = str2num(app.XEditField.Value);
            y = str2num(app.YEditField.Value);
            xs = str2num(app.XInterpolLabel.Value);

            xm = min(x);
            xM=max(x);
            x_interpol=xm:0.1:xM;

            n = length(x);
            m = length(x_interpol);
            resultado = zeros(1, m);

            for k = 1:m
                L = ones(1, n);

                for i = 1:n
                    for j = 1:n
                        if i ~= j
                            L(i) = L(i) * (x_interpol(k) - x(j)) / (x(i) - x(j));
                        end
                    end
                end

                resultado(k) = sum(y .* L);
            end

            D = polyfit(x,y,n);
            app.ResultadoEditField.Value=polyval(D,xs);

            % Actualizar el gráfico con puntos de entrada y curva interpolada
            plot(app.UIAxes, x, y,'o', 'MarkerSize', 12, 'MarkerFaceColor', 'y')
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x_interpol, resultado, 'm-', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, 'Puntos de entrada', 'Interpolación de Lagrange', 'Location', 'Best');
            grid(app.UIAxes, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Interpolación por método Lagrange')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'cool')
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterpolarButton = uibutton(app.UIFigure, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.ValoresaInterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.ValoresaInterpolarEditFieldLabel.Text = 'Valores a Interpolar';
            app.XInterpolLabel = uieditfield(app.UIFigure, 'text');
            app.XInterpolLabel.Value = '3.6';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.XEditField.Value = '1 2 3 4';
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.YEditField.Value = '2 1 3 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolacionLa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end