classdef NewtonR1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RazEditField              matlab.ui.control.EditField
        RazEditFieldLabel         matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        PlayButton                matlab.ui.control.Button
        GrficoButton              matlab.ui.control.Button
        ToleranciaEditField       matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel  matlab.ui.control.Label
        x0EditField               matlab.ui.control.NumericEditField
        x0EditFieldLabel          matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function x1= Newton(~,f,x0,tol)    
            fx0 = double(subs(f,x0)); %f(x0)
            df=diff(f); % derivada de la función f
            dfx0 = double(subs(df,x0)); %f'(x0)
            x1 = x0-fx0/dfx0;
            err = abs(x1-x0); % Error absoluto
            while err >= tol && dfx0 ~= 0
                x0=x1;
                fx0=double(subs(f,x0));
                dfx0 = double(subs(df,x0));
                x1 = x0-fx0/dfx0;
                err = abs(x1-x0);
            end
            x1 = num2str(x1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrficoButton
        function GrficoButtonPushed(app, event)
            f=app.fxEditField.Value; % Extrae la función en Texto
            f = ['@(x)',f];% Construir la función anónima
            f=str2func(f); % Convierte el texto a función anónima
            
            a = app.aEditField.Value; % Límite inferior
            b = app.bEditField.Value; % Límite Superior
            
            fplot(app.UIAxes,f,[a,b],'Color','black','LineWidth',2); % Gráfica la función respecto a sus límites
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            f=app.fxEditField.Value; % Extrae la función en Texto
            f = ['@(x)',f];
            f=str2func(f); % Convierte el texto a función anónima

            tol = app.ToleranciaEditField.Value; % La tolerancia especificada
            x0 = app.x0EditField.Value; 
            f = sym(f);
            c = app.Newton(f,x0,tol);
            app.RazEditField.Value = c; % Asigna el valor de la raiz a el campo "Raiz"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = 'x^5-x-1';
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.Value = 1;
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranciaEditField.Value = 1e-06;
            app.GrficoButton = uibutton(app.UIFigure, 'push');
            app.GrficoButton.ButtonPushedFcn = createCallbackFcn(app, @GrficoButtonPushed, true);
            app.GrficoButton.Text = 'Gráfico';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -3;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 3;
            app.RazEditFieldLabel = uilabel(app.UIFigure);
            app.RazEditFieldLabel.Text = 'Raíz';
            app.RazEditField = uieditfield(app.UIFigure, 'text');
            app.RazEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonR1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end