classdef SEL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SistemasdeEcuacionesLinealesLabel  matlab.ui.control.Label
        MtodoDropDown           matlab.ui.control.DropDown
        MtodoLabel              matlab.ui.control.Label
        UITable2                matlab.ui.control.Table
        NEditField              matlab.ui.control.NumericEditField
        NEditFieldLabel         matlab.ui.control.Label
        PlayButton              matlab.ui.control.Button
        NmerodeincgnitasButton  matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function X=inversa(~,A,b)
            %~ se coloca para indicar que no vamos a usar nada de la aplicación
            D=det(A); %determinante de la matriz, la determinante no debe ser cero
            if D~=0
                X=A\b;
            end
            
        end
        
        function X=metodo_cramer(~,A,b)
            n=length(b);
            X=zeros(n,1);
            if det(A)~=0
                for j=1:n
                    B=A;
                    B(:,j)=b;        % B=A
                    X(j)=det(B)/det(A);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            Data={"","","",""}; 
            app.UITable.Data=Data; %para datos de tabla se pone .Data en vez de .Value
        end

        % Button pushed function: NmerodeincgnitasButton
        function NmerodeincgnitasButtonPushed(app, event)
            N=app.NEditField.Value; %extraemos el valor de N
            A=cell(N); %genera una celda donde almacena a N en la celda A, en este paso está vacía la celda
            app.UITable.Data=A;
            b=cell(N,1);
            app.UITable2.Data=b;

            %editamos las columnas
            NombreColumnas=cell(1,N); %tiene una sola fila y N columnas, se crean para añadir las columnas
            
            %con el ciclo le damos nombre a las columnas
            for i=1:N
                NombreColumnas{i}=sprintf('X%d',i); %se ponen { porque las celdas se crean en llaves, y si queremos acceder a ellas 
                %también es entre llaves, ejemplo A{i(que es el índice)}=Nombre
            end
            app.UITable.ColumnName=NombreColumnas; %añadimos el nombre delas columnas

            %configuramos las tablas para poder editarlas, se colocan
            %números no letras
            app.UITable.ColumnEditable=true; %para la primera tabla
            app.UITable2.ColumnEditable=true; %para la segunda tabla

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            A=app.UITable.Data;
            % A=table2array(A);%extrae datos de la tabla, como en una tabla
            A=str2double(A); %usamos double para los decimales
            b=app.UITable2.Data; 
            % b=table2array(b);%extrae datos de la tabla, como en una tabla
            b=str2double(b); %usamos double para los decimales
           
            %preguntar a charlie orden en que se colocan porque lo dijo muy rápido
            %extre los vales de la tabla uno y dos, hay que volverlos
            %matriz, luego se extraen de la matriz en cadena  y los pasamos a número


            metodo=app.MtodoDropDown.Value; %extraemos el valor del dropdown para saber que método usamos
            if metodo=="Cramer"
                X=app.metodo_cramer(A,b); %con las condicionales indicamos que método usamos
            elseif metodo=="Inversa"
                X=app.inversa(A,b);
            end

            %transformamos a columnas porque lo arroja como matriz
            X=num2cell(X); %lo convertimos a celda
            app.UITable2.Data=X;
            N=app.NEditField.Value;
            V=eye(N); %crea la filas de unos
            V=num2cell(V); %convertimos a celda
            app.UITable.Data=V;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NmerodeincgnitasButton = uibutton(app.UIFigure, 'push');
            app.NmerodeincgnitasButton.ButtonPushedFcn = createCallbackFcn(app, @NmerodeincgnitasButtonPushed, true);
            app.NmerodeincgnitasButton.Text = 'Número de incógnitas';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 4;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'y'};
            app.UITable2.RowName = {};
            app.MtodoLabel = uilabel(app.UIFigure);
            app.MtodoLabel.Text = 'Método ';
            app.MtodoDropDown = uidropdown(app.UIFigure);
            app.MtodoDropDown.Items = {'Inversa', 'Cramer'};
            app.MtodoDropDown.Value = 'Inversa';
            app.SistemasdeEcuacionesLinealesLabel = uilabel(app.UIFigure);
            app.SistemasdeEcuacionesLinealesLabel.Text = 'Sistemas de Ecuaciones Lineales';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end