classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        ToleranciaEditField       matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel  matlab.ui.control.Label
        RazEditField              matlab.ui.control.EditField
        RazEditFieldLabel         matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        PlayButton                matlab.ui.control.Button
        GraficoButton             matlab.ui.control.Button
        x0EditField               matlab.ui.control.NumericEditField
        x0EditFieldLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficoButton
        function GraficoButtonPushed(app, event)
            f=app.fxEditField.Value;  %EStraer el valor de la casilla f(x)
            f=['@(x)',f]; %concadenado
            f=str2func(f); %convertir la funcion f a una funcion anonima
            a=app.aEditField.Value; 
            b=app.bEditField.Value;
            fplot(app.UIAxes,f,[a,b],'color','black','LineWidth',2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.GraficoButton = uibutton(app.UIFigure, 'push');
            app.GraficoButton.ButtonPushedFcn = createCallbackFcn(app, @GraficoButtonPushed, true);
            app.GraficoButton.Text = 'Grafico';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -2;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 3;
            app.RazEditFieldLabel = uilabel(app.UIFigure);
            app.RazEditFieldLabel.Text = 'Raíz';
            app.RazEditField = uieditfield(app.UIFigure, 'text');
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f}(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = 'x^3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end